classdef HW1_M11003330 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Label_title  matlab.ui.control.Label
        Label_out    matlab.ui.control.Label
        Label_4      matlab.ui.control.Label
        Label_3      matlab.ui.control.Label
        Label_2      matlab.ui.control.Label
        Label_1      matlab.ui.control.Label
        button_4     matlab.ui.control.Button
        button_3     matlab.ui.control.Button
        button_2     matlab.ui.control.Button
        button_1     matlab.ui.control.Button
    end

    
    properties (Access = private)
        Im; % 影像資料
        Button_Icon; % 各個按鈕的影像
        TurnRedSeq = randperm(4); % 變紅順序
        T_start = zeros(1, 4); % 起始時間
        T_end = zeros(1, 4); % 按下時間

        Buttons; % 將Button統整，用於Callbacks Function
        Labels; % 將Label統整，用於Callbacks Function
    end
    
    properties (Access = public)
        % 標記按鈕操作狀態，設定為公開，用以確認遊玩狀態
        % 各個數值意思 (0:未開始； 1:待完成； 2:已完成)
        ButtonState = zeros(1, 4); 
    end
    
    methods (Access = private)
        

        %func1-1
        function m_SetImage(app)
            % 讀取影像，並先將每個按鈕的圖片處理、存放

            [fName, pName] = uigetfile({'*.png; *.jpeg; *.jpg'}, "MultiSelect", "off");
            app.Im = imread([pName fName]);
            [Im_Row, Im_Col, ~] = size(app.Im);
            Im_Vert = floor(Im_Row / 2);
            Im_hor = floor(Im_Col / 2);

            if Im_Vert > Im_hor % 將影像長寬適當調整後分別存放
                app.Button_Icon{1} = imresize(app.Im(1 : Im_Vert, 1 : Im_hor, :), [NaN 160]);
                app.Button_Icon{2} = imresize(app.Im(1 :Im_Vert, Im_hor : end, :), [NaN 160]);
                app.Button_Icon{3} = imresize(app.Im(Im_Vert : end, 1 : Im_hor, :), [NaN 160]);
                app.Button_Icon{4} = imresize(app.Im(Im_Vert : end, Im_hor : end, :), [NaN 160]);
            else
                app.Button_Icon{1} = imresize(app.Im(1 : Im_Vert, 1 : Im_hor, :), [160 NaN]);
                app.Button_Icon{2} = imresize(app.Im(1 :Im_Vert, Im_hor : end, :), [160 NaN]);
                app.Button_Icon{3} = imresize(app.Im(Im_Vert : end, 1 : Im_hor, :), [160 NaN]);
                app.Button_Icon{4} = imresize(app.Im(Im_Vert : end, Im_hor : end, :), [160 NaN]);
            end
        end


        %func1-2
        function m_TurnRed(app) 
            % 按鈕運行程式，其中包含變紅，以及標記
            % buttons及labels控制按鈕及成績的相關互動
            % 將計時資料存放於T_start和T_end

            for Start_Button = 1:4
                pause(1.5); % 每1.5秒觸發一個按鈕
                StartOrder = app.TurnRedSeq(Start_Button); % 選擇觸發的按鈕
                app.ButtonState(StartOrder) = 1; % 標記以觸發的按鈕
                app.Buttons{StartOrder}.BackgroundColor = 'r'; % 按鈕變紅
                app.T_start(StartOrder) = tic; % 開始對目標按鈕計時
            end
        end


        % 通用的Pushed_Callback Function
        function m_ButtonSet(app, ButtonIdx)
            % Button的Callback Function
            % 包含計時，顯示影像、成績

            if app.ButtonState(ButtonIdx) == 1 % 確認按鈕是否已經變紅
                app.ButtonState(ButtonIdx) = 2; % 將按鈕設定為已完成
                app.T_end(ButtonIdx)= toc(uint64(app.T_start(ButtonIdx))); % 計算按鈕變紅到按下間的時間
                app.Buttons{ButtonIdx}.Icon = app.Button_Icon{ButtonIdx}; % 將按鈕圖示設定為影像
                app.Labels{ButtonIdx}.Text = sprintf('%.3f', app.T_end(ButtonIdx)); % 計算時間
                app.Labels{ButtonIdx}.Visible = 'On';
            end
        end

        
        %func2
        function m_End(app)
            % 確認遊玩結束，執行結束畫面
            % 輸出最後結果，結束程式

            waitfor(app, 'ButtonState', [2 2 2 2]); % 確認遊玩結束
            for t = 1:3 % 閃爍3次
                app.UIFigure.Color = 'r';
                pause(.2);
                app.UIFigure.Color = 'w';
                pause(.2);
            end

            app.Label_title.Position(2) = 240; % 將標題放置畫面中央
            pause(.6)
            for Drop = 0:10:280 % 標題掉落
                app.Label_title.Position(2) = app.Label_title.Position(2) - 10; % 以每次減10落下
                pause(.08)
            end
            
            app.Label_out.Text = sprintf("您一共花了 %.3f 秒!", sum(app.T_end)); % 計算、顯示總花費時機間
            app.Label_out.Visible = "on"; 

            pause(4); % 等待四秒後關閉
            app.delete;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Buttons = {app.button_1, app.button_2, app.button_3, app.button_4};
            app.Labels = {app.Label_1, app.Label_2, app.Label_3, app.Label_4};
            
            app.m_SetImage();
            app.m_TurnRed();
            app.m_End
        end

        % Button pushed function: button_1
        function button_1ButtonPushed(app, event)
            ButtonIdx = 1;
            app.m_ButtonSet(ButtonIdx)
        end

        % Button pushed function: button_2
        function button_2ButtonPushed(app, event)
            ButtonIdx = 2;
            app.m_ButtonSet(ButtonIdx)
        end

        % Button pushed function: button_3
        function button_3ButtonPushed(app, event)
            ButtonIdx = 3;
            app.m_ButtonSet(ButtonIdx)
        end

        % Button pushed function: button_4
        function button_4ButtonPushed(app, event)
            ButtonIdx = 4;
            app.m_ButtonSet(ButtonIdx)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.button_1 = uibutton(app.UIFigure, 'push');
            app.button_1.ButtonPushedFcn = createCallbackFcn(app, @button_1ButtonPushed, true);
            app.button_1.Text = '';
            app.button_2 = uibutton(app.UIFigure, 'push');
            app.button_2.ButtonPushedFcn = createCallbackFcn(app, @button_2ButtonPushed, true);
            app.button_2.Text = '';
            app.button_3 = uibutton(app.UIFigure, 'push');
            app.button_3.ButtonPushedFcn = createCallbackFcn(app, @button_3ButtonPushed, true);
            app.button_3.Text = '';
            app.button_4 = uibutton(app.UIFigure, 'push');
            app.button_4.ButtonPushedFcn = createCallbackFcn(app, @button_4ButtonPushed, true);
            app.button_4.Text = '';
            app.Label_1 = uilabel(app.UIFigure);
            app.Label_1.Visible = 'off';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Visible = 'off';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Visible = 'off';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Visible = 'off';
            app.Label_out = uilabel(app.UIFigure);
            app.Label_out.Visible = 'off';
            app.Label_out.Text = 'Out';
            app.Label_title = uilabel(app.UIFigure);
            app.Label_title.Text = 'M11003330 : 影像打地鼠';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HW1_M11003330

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end